classdef PingPongRobotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        PlayModeTab                    matlab.ui.container.Tab
        StateLampLabel                 matlab.ui.control.Label
        StateLamp                      matlab.ui.control.Lamp
        PLAYButton                     matlab.ui.control.StateButton
        E_STOP_Button                  matlab.ui.control.StateButton
        ROSMASTERIPEditFieldLabel      matlab.ui.control.Label
        ROSMASTERIPEditField           matlab.ui.control.EditField
        ROSIPEditFieldLabel            matlab.ui.control.Label
        ROSIPEditField                 matlab.ui.control.EditField
        InitialiazeSimulationButton    matlab.ui.control.Button
        TeachRobotTab                  matlab.ui.container.Tab
        Joint1SliderLabel              matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint2SliderLabel              matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint3SliderLabel              matlab.ui.control.Label
        Joint3Slider                   matlab.ui.control.Slider
        Joint4SliderLabel              matlab.ui.control.Label
        Joint4Slider                   matlab.ui.control.Slider
        Joint5SliderLabel              matlab.ui.control.Label
        Joint5Slider                   matlab.ui.control.Slider
        Joint6SliderLabel              matlab.ui.control.Label
        Joint6Slider                   matlab.ui.control.Slider
        Joint7SliderLabel              matlab.ui.control.Label
        Joint7Slider                   matlab.ui.control.Slider
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EndEffectorPanel               matlab.ui.container.Panel
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        REditFieldLabel                matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        PEditFieldLabel                matlab.ui.control.Label
        PEditField                     matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_2                   matlab.ui.control.NumericEditField
        TrajectoryMethodDropDownLabel  matlab.ui.control.Label
        TrajectoryMethodDropDown       matlab.ui.control.DropDown
        UnityRobotDriveButton          matlab.ui.control.Button
        MATLABTestDriveButton          matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        JointsButton                   matlab.ui.control.RadioButton
        EndEffectorButton              matlab.ui.control.RadioButton
        JogRobotTab                    matlab.ui.container.Tab
        JogOptionsButtonGroup          matlab.ui.container.ButtonGroup
        SlidersButton                  matlab.ui.control.RadioButton
        ControllerButton               matlab.ui.control.RadioButton
        JointVelocityControlPanel      matlab.ui.container.Panel
        Joint1Slider_2Label            matlab.ui.control.Label
        Joint1Slider_2                 matlab.ui.control.Slider
        Joint4Slider_2Label            matlab.ui.control.Label
        Joint4Slider_2                 matlab.ui.control.Slider
        Joint5Slider_2Label            matlab.ui.control.Label
        Joint5Slider_2                 matlab.ui.control.Slider
        Joint6Slider_2Label            matlab.ui.control.Label
        Joint6Slider_2                 matlab.ui.control.Slider
        Joint7Slider_2Label            matlab.ui.control.Label
        Joint7Slider_2                 matlab.ui.control.Slider
        Joint3Slider_2Label            matlab.ui.control.Label
        Joint3Slider_2                 matlab.ui.control.Slider
        Joint2Slider_2Label            matlab.ui.control.Label
        Joint2Slider_2                 matlab.ui.control.Slider
        CartesianVelocityControlPanel  matlab.ui.container.Panel
        XSliderLabel                   matlab.ui.control.Label
        XSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        ZSlider                        matlab.ui.control.Slider
        RSliderLabel                   matlab.ui.control.Label
        RSlider                        matlab.ui.control.Slider
        PSliderLabel                   matlab.ui.control.Label
        PSlider                        matlab.ui.control.Slider
        YSlider_2Label                 matlab.ui.control.Label
        YSlider_2                      matlab.ui.control.Slider
        RightPanel                     matlab.ui.container.Panel
        DEBUGWINDOWTextAreaLabel       matlab.ui.control.Label
        DEBUGWINDOWTextArea            matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        debugWindowText = ''; % Description
    end
    
    methods (Access = private)
        
        function Debug(app, text)
            app.debugWindowText = sprintf("%s\n%s",text,app.debugWindowText);
            app.DEBUGWINDOWTextArea.Value = app.debugWindowText;
        end

        
        function jointStates = GetRobotJointStateFieldApp(app)
            
            Q = zeros(1,7);
            
            Q(1) = deg2rad(app.EditField.Value);
            Q(2) = deg2rad(app.EditField_2.Value);
            Q(3) = deg2rad(app.EditField_3.Value);
            Q(4) = deg2rad(app.EditField_4.Value);
            Q(5) = deg2rad(app.EditField_5.Value);
            Q(6) = deg2rad(app.EditField_6.Value);
            Q(7) = deg2rad(app.EditField_7.Value);
            
            jointStates = Q;
            
        end
        
        function jointStates = GetRobotJointStateSliderApp(app)
            
            Q = zeros(1,7);
            
            Q(1) = deg2rad(app.Joint1Slider.Value);
            Q(2) = deg2rad(app.Joint2Slider.Value);
            Q(3) = deg2rad(app.Joint3Slider.Value);
            Q(4) = deg2rad(app.Joint4Slider.Value);
            Q(5) = deg2rad(app.Joint5Slider.Value);
            Q(6) = deg2rad(app.Joint6Slider.Value);
            Q(7) = deg2rad(app.Joint7Slider.Value);
            
            jointStates = Q;
            
        end
        
        function endEffPose = GetEndEffPoseApp(app)
            
            tr = [rpy2r(app.REditField.Value,app.PEditField.Value,app.YEditField_2.Value),...
                [app.XEditField.Value;app.YEditField.Value;app.ZEditField.Value]';0 0 0 1];
            
            endEffPose = tr;
            
        end
        
        function UpdateXYZRPY(app, endEffPose)
            
            rpy = tr2rpy(endEffPose);
            
            app.XEditField.Value = endEffPose(1,4);
            app.YEditField.Value = endEffPose(2,4);
            app.ZEditField.Value = endEffPose(3,4);
            app.REditField.Value = rpy(1);
            app.PEditField.Value = rpy(2);
            app.YEditField_2.Value = rpy(3);
            
        end
        
        function cartVel = GetCartesianVelocityApp(app, Kv, Kw)
            
            if nargin < 3
                Kv = 0.2;
                Kw = 1;
            end
            
            cartVel = [Kv * app.XSlider.Value; ...
                Kv * app.YSlider.Value; ...
                Kv * app.ZSlider.Value; ...
                Kw * app.RSlider.Value; ...
                Kw * app.PSlider.Value; ...
                Kw * app.YSlider.Value];
            
        end
        
        function qVel = GetJointsVelocitiesApp(app)
            
            qVel = [ 0.1 * app.Joint1Slider_2.Value, ...
                pi * [app.Joint2Slider_2.Value, ...
                app.Joint3Slider_2.Value, ...
                app.Joint4Slider_2.Value, ...
                app.Joint5Slider_2.Value, ...
                app.Joint6Slider_2.Value, ...
                app.Joint7Slider_2.Value]];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: PLAYButton
        function PLAYButtonValueChanged(app, event)
            value = app.PLAYButton.Value;
            if value
                app.PLAYButton.BackgroundColor = [0 0 1];
                app.PLAYButton.Text = "PAUSE";
                app.StateLampLabel.Text = "Running";
                app.StateLamp.Color = [0 1 0];
            elseif ~value
                app.PLAYButton.BackgroundColor = [0 1 0];
                app.PLAYButton.Text = "PLAY";
                app.StateLampLabel.Text = "Pause";
                app.StateLamp.Color = [0 0 1];
            end
        end

        % Value changed function: E_STOP_Button
        function E_STOP_ButtonValueChanged(app, event)
            value = app.E_STOP_Button.Value;
            
            if value
                app.StateLampLabel.Text = "Stop";
                app.StateLamp.Color = [1 0 0];
            elseif ~value
                app.StateLampLabel.Text = "Pause";
                app.StateLamp.Color = [0 0 1];
            end
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            app.EditField.Value = value;
            
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            app.EditField_2.Value = value;
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            app.EditField_3.Value = value;
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            app.EditField_4.Value = value;
            
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value;
            app.EditField_5.Value = value;
            
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value;
            app.EditField_6.Value = value;
            
        end

        % Value changed function: Joint7Slider
        function Joint7SliderValueChanged(app, event)
            value = app.Joint7Slider.Value;
            app.EditField_7.Value = value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Joint1Slider.Value = value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Joint2Slider.Value = value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Joint3Slider.Value = value;
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Joint4Slider.Value = value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.Joint5Slider.Value = value;
            
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.Joint6Slider.Value = value;
            
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.Joint7Slider.Value = value;
            
        end

        % Button pushed function: UnityRobotDriveButton
        function UnityRobotDriveButtonPushed(app, event)
            app.Debug("Button Pushed");
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Debug(sprintf("%0.3f", changingValue));
            
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            app.XSlider.Value = 0;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {712, 712};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {434, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PlayModeTab = uitab(app.TabGroup);
            app.PlayModeTab.Title = 'Play Mode';
            app.StateLampLabel = uilabel(app.PlayModeTab);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.PlayModeTab);
            app.PLAYButton = uibutton(app.PlayModeTab, 'state');
            app.PLAYButton.ValueChangedFcn = createCallbackFcn(app, @PLAYButtonValueChanged, true);
            app.PLAYButton.Text = 'PLAY';
            app.E_STOP_Button = uibutton(app.PlayModeTab, 'state');
            app.E_STOP_Button.ValueChangedFcn = createCallbackFcn(app, @E_STOP_ButtonValueChanged, true);
            app.E_STOP_Button.Icon = 'emergency-stop.jpg';
            app.E_STOP_Button.Text = '';
            app.ROSMASTERIPEditFieldLabel = uilabel(app.PlayModeTab);
            app.ROSMASTERIPEditFieldLabel.Text = 'ROS MASTER IP';
            app.ROSMASTERIPEditField = uieditfield(app.PlayModeTab, 'text');
            app.ROSIPEditFieldLabel = uilabel(app.PlayModeTab);
            app.ROSIPEditFieldLabel.Text = 'ROS IP';
            app.ROSIPEditField = uieditfield(app.PlayModeTab, 'text');
            app.InitialiazeSimulationButton = uibutton(app.PlayModeTab, 'push');
            app.InitialiazeSimulationButton.Text = 'Initialiaze Simulation';
            app.TeachRobotTab = uitab(app.TabGroup);
            app.TeachRobotTab.Title = 'Teach Robot';
            app.Joint1SliderLabel = uilabel(app.TeachRobotTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.TeachRobotTab);
            app.Joint1Slider.Limits = [-0.8 0];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.TeachRobotTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.TeachRobotTab);
            app.Joint2Slider.Limits = [-360 360];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.TeachRobotTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.TeachRobotTab);
            app.Joint3Slider.Limits = [-360 360];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.TeachRobotTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.TeachRobotTab);
            app.Joint4Slider.Limits = [-360 360];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.TeachRobotTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.TeachRobotTab);
            app.Joint5Slider.Limits = [-360 360];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.TeachRobotTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.TeachRobotTab);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint7SliderLabel = uilabel(app.TeachRobotTab);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.TeachRobotTab);
            app.Joint7Slider.Limits = [-360 360];
            app.Joint7Slider.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderValueChanged, true);
            app.EditField = uieditfield(app.TeachRobotTab, 'numeric');
            app.EditField.Limits = [-0.8 0];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.TeachRobotTab, 'numeric');
            app.EditField_2.Limits = [-360 360];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.TeachRobotTab, 'numeric');
            app.EditField_3.Limits = [-360 360];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.TeachRobotTab, 'numeric');
            app.EditField_4.Limits = [-360 360];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.TeachRobotTab, 'numeric');
            app.EditField_5.Limits = [-360 360];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.TeachRobotTab, 'numeric');
            app.EditField_6.Limits = [-360 360];
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.TeachRobotTab, 'numeric');
            app.EditField_7.Limits = [-360 360];
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EndEffectorPanel = uipanel(app.TeachRobotTab);
            app.EndEffectorPanel.Title = 'End-Effector';
            app.XEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.REditFieldLabel = uilabel(app.EndEffectorPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.PEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.EndEffectorPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.EndEffectorPanel, 'numeric');
            app.TrajectoryMethodDropDownLabel = uilabel(app.EndEffectorPanel);
            app.TrajectoryMethodDropDownLabel.Text = 'Trajectory Method';
            app.TrajectoryMethodDropDown = uidropdown(app.EndEffectorPanel);
            app.TrajectoryMethodDropDown.Items = {'RMRC', 'IK (Trapezoidal)', 'IK (Quintic)'};
            app.TrajectoryMethodDropDown.Value = 'RMRC';
            app.UnityRobotDriveButton = uibutton(app.EndEffectorPanel, 'push');
            app.UnityRobotDriveButton.ButtonPushedFcn = createCallbackFcn(app, @UnityRobotDriveButtonPushed, true);
            app.UnityRobotDriveButton.Text = 'Unity Robot Drive';
            app.MATLABTestDriveButton = uibutton(app.EndEffectorPanel, 'push');
            app.MATLABTestDriveButton.Text = 'MATLAB Test Drive';
            app.ButtonGroup = uibuttongroup(app.TeachRobotTab);
            app.JointsButton = uiradiobutton(app.ButtonGroup);
            app.JointsButton.Text = 'Joints';
            app.JointsButton.Value = true;
            app.EndEffectorButton = uiradiobutton(app.ButtonGroup);
            app.EndEffectorButton.Text = 'End Effector';
            app.JogRobotTab = uitab(app.TabGroup);
            app.JogRobotTab.Title = 'Jog Robot';
            app.JogOptionsButtonGroup = uibuttongroup(app.JogRobotTab);
            app.JogOptionsButtonGroup.Title = 'Jog Options';
            app.SlidersButton = uiradiobutton(app.JogOptionsButtonGroup);
            app.SlidersButton.Text = 'Sliders';
            app.SlidersButton.Value = true;
            app.ControllerButton = uiradiobutton(app.JogOptionsButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.JointVelocityControlPanel = uipanel(app.JogRobotTab);
            app.JointVelocityControlPanel.Title = 'Joint Velocity Control';
            app.Joint1Slider_2Label = uilabel(app.JointVelocityControlPanel);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider_2 = uislider(app.JointVelocityControlPanel);
            app.Joint1Slider_2.Limits = [-1 1];
            app.Joint4Slider_2Label = uilabel(app.JointVelocityControlPanel);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider_2 = uislider(app.JointVelocityControlPanel);
            app.Joint4Slider_2.Limits = [-1 1];
            app.Joint5Slider_2Label = uilabel(app.JointVelocityControlPanel);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider_2 = uislider(app.JointVelocityControlPanel);
            app.Joint5Slider_2.Limits = [-1 1];
            app.Joint6Slider_2Label = uilabel(app.JointVelocityControlPanel);
            app.Joint6Slider_2Label.Text = 'Joint 6';
            app.Joint6Slider_2 = uislider(app.JointVelocityControlPanel);
            app.Joint6Slider_2.Limits = [-1 1];
            app.Joint7Slider_2Label = uilabel(app.JointVelocityControlPanel);
            app.Joint7Slider_2Label.Text = 'Joint 7';
            app.Joint7Slider_2 = uislider(app.JointVelocityControlPanel);
            app.Joint7Slider_2.Limits = [-1 1];
            app.Joint3Slider_2Label = uilabel(app.JointVelocityControlPanel);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider_2 = uislider(app.JointVelocityControlPanel);
            app.Joint3Slider_2.Limits = [-1 1];
            app.Joint2Slider_2Label = uilabel(app.JointVelocityControlPanel);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider_2 = uislider(app.JointVelocityControlPanel);
            app.Joint2Slider_2.Limits = [-1 1];
            app.CartesianVelocityControlPanel = uipanel(app.JogRobotTab);
            app.CartesianVelocityControlPanel.Title = 'CartesianVelocity Control';
            app.XSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.CartesianVelocityControlPanel);
            app.XSlider.Limits = [-1 1];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.CartesianVelocityControlPanel);
            app.YSlider.Limits = [-1 1];
            app.ZSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.CartesianVelocityControlPanel);
            app.ZSlider.Limits = [-1 1];
            app.RSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.CartesianVelocityControlPanel);
            app.RSlider.Limits = [-1 1];
            app.PSliderLabel = uilabel(app.CartesianVelocityControlPanel);
            app.PSliderLabel.Text = 'P';
            app.PSlider = uislider(app.CartesianVelocityControlPanel);
            app.PSlider.Limits = [-1 1];
            app.YSlider_2Label = uilabel(app.CartesianVelocityControlPanel);
            app.YSlider_2Label.Text = 'Y';
            app.YSlider_2 = uislider(app.CartesianVelocityControlPanel);
            app.YSlider_2.Limits = [-1 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.DEBUGWINDOWTextAreaLabel = uilabel(app.RightPanel);
            app.DEBUGWINDOWTextAreaLabel.Text = 'DEBUG WINDOW';
            app.DEBUGWINDOWTextArea = uitextarea(app.RightPanel);
            app.DEBUGWINDOWTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PingPongRobotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end